classdef VideoPlayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        FreeEditingTab         matlab.ui.container.Tab
        UIAxesFreeEditing      matlab.ui.control.UIAxes
        Preview360Tab          matlab.ui.container.Tab
        UIAxes360Preview       matlab.ui.control.UIAxes
        RemoveKeyFrame         matlab.ui.control.Button
        AddKeyFrame            matlab.ui.control.Button
        ExportVideoButton      matlab.ui.control.Button
        viewDropDown           matlab.ui.control.DropDown
        Label                  matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        VideoFileBrowseButton  matlab.ui.control.Button
        VideoFileEditField     matlab.ui.control.EditField
        VideoFileLabel         matlab.ui.control.Label
        TimeStatusLabel        matlab.ui.control.Label
        RepeatButton           matlab.ui.control.StateButton
        StopButton             matlab.ui.control.Button
        PlayButton             matlab.ui.control.Button
        UIAxesProgress         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Graphics objects
        CursorLine % xline function return object type
        SurfaceTexture % surf function return object type
        hTransformObj % hgtransform function return object type

        % Data objects
        pVideoFileReader
        tEnd % video duration

        % Flags and other runtime data
        VideoFileName (1,1) string
        VideoFileReadPointer (1,1) double = 0
        PlaybackState (1,1) string {mustBeMember(PlaybackState, ...
            ["nodata", "ready", "playing", "paused"])} = "nodata"
        PauseRequested (1,1) logical = false
        StopRequested (1,1) logical = false
    end

    methods (Access = private)
        function setupAxes(app)
            % Disable axes interactivity for better performance.
            axFreeEditing = app.UIAxesFreeEditing;
            disableDefaultInteractivity(axFreeEditing);

            % uiaxes坐标范围归一化到容器内，默认与axes不一样！
            axFreeEditing.Units = "normalized";
            axFreeEditing.Position = [0,0,1,1];

            % 窗口默认工具条隐藏和设置相机初始化视角参数
            axFreeEditing.Toolbar.Visible = 'off';
            axFreeEditing.DataAspectRatio = [1,1,1];
           
            axFreeEditing.Projection = 'perspective';
            axFreeEditing.Visible='off';

            axis(axFreeEditing,"tight")
            axis(axFreeEditing,"off")

            % 360 equirectangular 图像预览轴
            ax360Preview = app.UIAxes360Preview;
            axtoolbar(ax360Preview, {'zoomin','zoomout','restoreview'});
            disableDefaultInteractivity(ax360Preview);
            axis(ax360Preview,"tight")
            axis(ax360Preview,"off")

            % 进度轴
            axProgress = app.UIAxesProgress;
            axProgress.XGrid="on";
            axProgress.Box = "on";
            
            disableDefaultInteractivity(axProgress);
        end

        function setupCamera(app)
            ax = app.UIAxesFreeEditing;
            ax.CameraPosition = [0,0,0];
            ax.CameraTarget = [1,0,0];
            ax.CameraUpVector = [0,0,-1];
            ax.CameraViewAngle = 60;
        end

        function createSurfTexture(app,frame)
            axFreeEditing = app.UIAxesFreeEditing;
            [X, Y, Z] = sphere(50);
            app.SurfaceTexture = surf(axFreeEditing, X, Y, Z,frame,'FaceColor','texture','EdgeColor','none');

            % 初始化转换对象
            app.hTransformObj = hgtransform(axFreeEditing);
            app.SurfaceTexture.Parent = app.hTransformObj;
        end

        function loadVideoFile(app)
            % Load video data 
            isValidVideoFIle = false;
            try
                app.pVideoFileReader = VideoReader(app.VideoFileName);
                isValidVideoFIle = true;
            catch ME
                uialert(app.UIFigure,ME.message,'Invalid File');
            end

            % Clear the axes and show first frame
            ax360preview = app.UIAxes360Preview;

            % Set up data objects and update app components
            if isValidVideoFIle
                frame = readFrame(app.pVideoFileReader);
                image(ax360preview,frame);
                createSurfTexture(app,frame);

                app.tEnd =  seconds(app.pVideoFileReader.Duration);
                app.tEnd.Format = 'hh:mm:ss';
                app.Slider.Limits = [0,app.pVideoFileReader.Duration+1];
                app.UIAxesProgress.XLim = [0,app.pVideoFileReader.Duration+1];
                app.UIAxesProgress.XTick = linspace(0,app.pVideoFileReader.Duration+1,100);
                app.CursorLine = xline(app.UIAxesProgress,0,Color="red",LineWidth=2);

                setPlaybackPosition(app,[]);
                % Update the playback toolbar buttons
                setPlaybackState(app,'ready');
            else
                setPlaybackState(app,'nodata');
            end
        end

        function setPlaybackState(app, playbackState)
            % Update the app components when the playback state changes

            % Disable loading an video file during playback
            app.VideoFileEditField.Enable = ~ismember(playbackState,{'playing','paused'});
            app.VideoFileBrowseButton.Enable = ~ismember(playbackState,{'playing','paused'});

            % Set playback buttons enableness
            app.PlayButton.Enable = ismember(playbackState,{'ready','playing','paused'});
            app.StopButton.Enable = ismember(playbackState,{'playing','paused'});
            app.RepeatButton.Enable = ismember(playbackState,{'ready'});

            % Set play button mode
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if strcmp(playbackState,'playing')
                app.PlayButton.Icon = fullfile(pathToMLAPP, 'Assets/media_control_pause_24.png');
                app.PlayButton.Tooltip = 'Pause';
            elseif strcmp(playbackState,'paused')
                app.PlayButton.Icon = fullfile(pathToMLAPP, 'Assets/media_control_play_24.png');
                app.PlayButton.Tooltip = 'Continue';
            else
                app.PlayButton.Icon = fullfile(pathToMLAPP, 'Assets/media_control_play_24.png');
                app.PlayButton.Tooltip = 'Play';
            end

            % Save the playback state and flush the graphics queue
            app.PlaybackState = playbackState;
            drawnow
        end

        function setPlaybackPosition(app,xLoc)
            % Set the playback position by updating the cursor location and
            % the time status
            if isempty(xLoc)
                app.Slider.Value = 0;
                app.CursorLine.Value = 0;
                tStr = string(duration(0,0,0,'Format','hh:mm:ss'));
            else
                app.Slider.Value = seconds(xLoc);
                app.CursorLine.Value = seconds(xLoc);
                xLoc.Format = 'hh:mm:ss';
                tStr = string(xLoc);
            end
            
            app.TimeStatusLabel.Text = sprintf('T = %s/%s',tStr,string(app.tEnd));
        end

        function play(app)
            reader = app.pVideoFileReader;
            axFreeEditing = app.UIAxesFreeEditing;
            ax360preview = app.UIAxes360Preview;

            % Start playback
            setPlaybackState(app, 'playing');
            while hasFrame(reader) &&  ~(app.StopRequested || app.PauseRequested)
                % Read video data and play
                frame = readFrame(reader);
                image(ax360preview,frame);
                app.SurfaceTexture.CData = frame;

                setPlaybackPosition(app,seconds(reader.CurrentTime));

                % Call drawnow to update graphics
                % drawnow %limitrate
                pause(1/reader.FrameRate);
            end

            if app.PauseRequested
                setPlaybackState(app, 'paused');
                app.PauseRequested = false;
            elseif app.StopRequested
                stop(app);
                app.StopRequested = false;
            else
                if app.RepeatButton.Value
                    app.pVideoFileReader.CurrentTime =0;
                    play(app);
                else
                    stop(app);
                    app.StopRequested = false;
                end
            end
        end

        function stop(app)
            app.pVideoFileReader.CurrentTime=0;
            frame = readFrame(app.pVideoFileReader);
            image(app.UIAxesFreeEditing,frame);
            createSurfTexture(app,frame);
            setupCamera(app)

            setPlaybackPosition(app,[]);
            setPlaybackState(app, 'ready');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create model objects
            setupAxes(app);
            % Load a default video file
            app.VideoFileName = './Assets/360panodemo.mp4';
            app.VideoFileEditField.Value = app.VideoFileName;
            loadVideoFile(app);
            setupCamera(app);
        end

        % Button pushed function: VideoFileBrowseButton
        function VideoFileBrowseButtonPushed(app, event)
            if (exist(app.VideoFileName,'file'))
                currFile = which(app.VideoFileName);
            else
                currFile = app.VideoFileName;
            end

            % Browse for video files using uigetfile
            dialogTitle = "Select Input Video File";
            filterSpec = {'*.mp4';'*.avi'};
            [filename, pathname] = uigetfile(filterSpec,dialogTitle,currFile);

            if filename
                % Change the property and update the edit field
                app.VideoFileName = fullfile(pathname,filename);
                app.VideoFileEditField.Value = app.VideoFileName;
                loadVideoFile(app);
                setupCamera(app);
            end
        end

        % Value changed function: VideoFileEditField
        function VideoFileEditFieldValueChanged(app, event)
            app.VideoFileName = app.VideoFileEditField.Value;
            loadVideoFile(app);
            setupCamera(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            try
                if app.PlaybackState == "playing"
                    app.PauseRequested = true;
                else
                    % A new start or continue previously paused
                    play(app);
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Error during playback');
                stop(app);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.PlaybackState == "playing"
                app.StopRequested = true;
            else
                stop(app);
            end
        end

        % Value changed function: RepeatButton
        function RepeatButtonValueChanged(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if app.RepeatButton.Value
                app.RepeatButton.Icon = fullfile(pathToMLAPP, 'Assets','media_control_repeat_on_24.png');
            else
                app.RepeatButton.Icon = fullfile(pathToMLAPP, 'Assets','media_control_repeat_off_24.png');
            end
        end

        % Value changing function: Slider
        function progressChangingSliderFcn(app, event)
            % 如果是播放状态，就应该立即暂停
            try
                if app.PlaybackState == "playing"
                    app.PauseRequested = true;
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Error during progressChangingSliderFcn');
                stop(app);
            end


            changingValue = event.Value;
            app.CursorLine.Value = changingValue;
            app.pVideoFileReader.CurrentTime = min(changingValue,app.pVideoFileReader.Duration);
            if hasFrame(app.pVideoFileReader)
                frame = readFrame(app.pVideoFileReader);
                image(app.UIAxes360Preview,frame);
                app.SurfaceTexture.CData = frame;
            end

            tStr = seconds(changingValue);
            tStr.Format = "hh:mm:ss";
            app.TimeStatusLabel.Text = sprintf('T = %s/%s',string(tStr),string(app.tEnd));

           % drawnow;% limitrate
        end

        % Value changed function: Slider
        function progressChangedFcn(app, event)
            % value = app.Slider.Value;
            value = event.Value;
            tStr = seconds(value);
            tStr.Format = "hh:mm:ss";
            app.TimeStatusLabel.Text = sprintf('T = %s/%s',string(tStr),string(app.tEnd));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '360 Panorama Studio (cuixingxing150@gmail.com)）';
            app.UIAxesProgress = uiaxes(app.UIFigure);
            app.UIAxesProgress.XTick = [];
            app.UIAxesProgress.XTickLabel = '';
            app.UIAxesProgress.YTick = [0 1];
            app.UIAxesProgress.YTickLabel = '';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'Assets', 'media_control_play_24.png');
            app.PlayButton.Tooltip = {'Play'};
            app.PlayButton.Text = '';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'Assets', 'media_control_stop_24.png');
            app.StopButton.Tooltip = {'Stop playing'};
            app.StopButton.Text = '';
            app.RepeatButton = uibutton(app.UIFigure, 'state');
            app.RepeatButton.ValueChangedFcn = createCallbackFcn(app, @RepeatButtonValueChanged, true);
            app.RepeatButton.Tooltip = {'Play in a loop'};
            app.RepeatButton.Icon = fullfile(pathToMLAPP, 'Assets', 'media_control_repeat_off_24.png');
            app.RepeatButton.Text = '';
            app.TimeStatusLabel = uilabel(app.UIFigure);
            app.TimeStatusLabel.Text = 'T = 00:00:00/ 00:00:00';
            app.VideoFileLabel = uilabel(app.UIFigure);
            app.VideoFileLabel.Text = 'Video File';
            app.VideoFileEditField = uieditfield(app.UIFigure, 'text');
            app.VideoFileEditField.ValueChangedFcn = createCallbackFcn(app, @VideoFileEditFieldValueChanged, true);
            app.VideoFileEditField.Placeholder = 'Enter video file path';
            app.VideoFileBrowseButton = uibutton(app.UIFigure, 'push');
            app.VideoFileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @VideoFileBrowseButtonPushed, true);
            app.VideoFileBrowseButton.Text = 'Browse';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @progressChangedFcn, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @progressChangingSliderFcn, true);
            app.Slider.MinorTicks = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'view';
            app.viewDropDown = uidropdown(app.UIFigure);
            app.viewDropDown.Items = {'perspective', 'littleplanet', 'crystalball'};
            app.viewDropDown.Value = 'perspective';
            app.ExportVideoButton = uibutton(app.UIFigure, 'push');
            app.ExportVideoButton.Text = 'Export Video';
            app.AddKeyFrame = uibutton(app.UIFigure, 'push');
            app.AddKeyFrame.Tooltip = {'Add Key Frame'};
            app.AddKeyFrame.Text = '+';
            app.RemoveKeyFrame = uibutton(app.UIFigure, 'push');
            app.RemoveKeyFrame.Tooltip = {'Remove Key Frame'};
            app.RemoveKeyFrame.Text = '-';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FreeEditingTab = uitab(app.TabGroup);
            app.FreeEditingTab.Tooltip = {'Free Editing,Generate Special Effect Video'};
            app.FreeEditingTab.Title = 'Free Editing';
            app.UIAxesFreeEditing = uiaxes(app.FreeEditingTab);
            app.UIAxesFreeEditing.XTickLabel = '';
            app.UIAxesFreeEditing.YTickLabel = '';
            app.Preview360Tab = uitab(app.TabGroup);
            app.Preview360Tab.Tooltip = {'Preview 360 Equirectangular Image'};
            app.Preview360Tab.Title = '360 Preview';
            app.UIAxes360Preview = uiaxes(app.Preview360Tab);
            app.UIAxes360Preview.XTick = [];
            app.UIAxes360Preview.YTick = [];
            app.UIAxes360Preview.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VideoPlayer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end